classdef MainApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        FileMenu                      matlab.ui.container.Menu
        ExitMenu                      matlab.ui.container.Menu
        ViewMenu                      matlab.ui.container.Menu
        PlotOptionsMenu               matlab.ui.container.Menu
        EnablehighqualitymodelsMenu   matlab.ui.container.Menu
        EnablecoordinateframeMenu     matlab.ui.container.Menu
        EnableghostmodelsMenu         matlab.ui.container.Menu
        ToolsMenu                     matlab.ui.container.Menu
        WindowMenu                    matlab.ui.container.Menu
        HelpMenu                      matlab.ui.container.Menu
        AboutMenu                     matlab.ui.container.Menu
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        SimulationPanel               matlab.ui.container.Panel
        DynamicSwitch                 matlab.ui.control.Switch
        DynamicSwitchLabel            matlab.ui.control.Label
        RunButton                     matlab.ui.control.Button
        TrajectoryPanel               matlab.ui.container.Panel
        VelEditField                  matlab.ui.control.NumericEditField
        VelEditFieldLabel             matlab.ui.control.Label
        AccEditField                  matlab.ui.control.NumericEditField
        AccEditFieldLabel             matlab.ui.control.Label
        InterpolationDropDown         matlab.ui.control.DropDown
        InterpolationLabel            matlab.ui.control.Label
        Label_2                       matlab.ui.control.Label
        Label                         matlab.ui.control.Label
        LimitsLabel                   matlab.ui.control.Label
        TabGroup                      matlab.ui.container.TabGroup
        RobotTab                      matlab.ui.container.Tab
        EndEffectorDropDown           matlab.ui.control.DropDown
        EndEffectorDropDownLabel      matlab.ui.control.Label
        UITable                       matlab.ui.control.Table
        ModelDropDown                 matlab.ui.control.DropDown
        ModelDropDownLabel            matlab.ui.control.Label
        CenterPanel                   matlab.ui.container.Panel
        Panel                         matlab.ui.container.Panel
        secLabel                      matlab.ui.control.Label
        TimeLabel                     matlab.ui.control.Label
        HighqualitymodelsSwitch       matlab.ui.control.Switch
        HighqualitymodelsSwitchLabel  matlab.ui.control.Label
        SlowerLabel                   matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
        RightPanel                    matlab.ui.container.Panel
        TabGroup_2                    matlab.ui.container.TabGroup
        SliderTab                     matlab.ui.container.Tab
        GripperSlider                 matlab.ui.control.Slider
        GripperSliderLabel            matlab.ui.control.Label
        ButtonGroup                   matlab.ui.container.ButtonGroup
        MotionPositionLabel           matlab.ui.control.Label
        FinalButton                   matlab.ui.control.RadioButton
        InitialButton                 matlab.ui.control.RadioButton
        Joint7Slider                  matlab.ui.control.Slider
        Joint7SliderLabel             matlab.ui.control.Label
        Joint6Slider                  matlab.ui.control.Slider
        Joint6SliderLabel             matlab.ui.control.Label
        Joint5Slider                  matlab.ui.control.Slider
        Joint5SliderLabel             matlab.ui.control.Label
        Joint4Slider                  matlab.ui.control.Slider
        Joint4SliderLabel             matlab.ui.control.Label
        Joint3Slider                  matlab.ui.control.Slider
        Joint3SliderLabel             matlab.ui.control.Label
        Joint2Slider                  matlab.ui.control.Slider
        Joint2SliderLabel             matlab.ui.control.Label
        Joint1Slider                  matlab.ui.control.Slider
        Joint1SliderLabel             matlab.ui.control.Label
        SpinnerTab                    matlab.ui.container.Tab
        Axis7FinalSpinner             matlab.ui.control.Spinner
        Axis7SpinnerLabel             matlab.ui.control.Label
        Axis7InitialSpinner           matlab.ui.control.Spinner
        Axis6FinalSpinner             matlab.ui.control.Spinner
        Axis6SpinnerLabel             matlab.ui.control.Label
        Axis5FinalSpinner             matlab.ui.control.Spinner
        Axis5SpinnerLabel             matlab.ui.control.Label
        Axis4FinalSpinner             matlab.ui.control.Spinner
        Axis4SpinnerLabel             matlab.ui.control.Label
        Axis3FinalSpinner             matlab.ui.control.Spinner
        Axis3SpinnerLabel             matlab.ui.control.Label
        Axis2FinalSpinner             matlab.ui.control.Spinner
        Axis2SpinnerLabel             matlab.ui.control.Label
        Axis1FinalSpinner             matlab.ui.control.Spinner
        Axis1SpinnerLabel             matlab.ui.control.Label
        Axis6InitialSpinner           matlab.ui.control.Spinner
        Axis5InitialSpinner           matlab.ui.control.Spinner
        Axis4InitialSpinner           matlab.ui.control.Spinner
        Axis3InitialSpinner           matlab.ui.control.Spinner
        Axis2InitialSpinner           matlab.ui.control.Spinner
        Axis1InitialSpinner           matlab.ui.control.Spinner
        FinalpointsLabel              matlab.ui.control.Label
        InitialpointsLabel            matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end


    properties (Access = private)
        
        prcnt = [0.1,0.1];
        trj_profile_text = 'Quintic';

        tini = 0; % Start time

    end
    
    properties (Access = public)
        
        robot_name = [
        "Franka Emika Robot"
        "Universal Robots UR3"
        "Unitree Z1"
        "Stäubli RX160"
        "Stäubli RX160L"];

        ms;
        Pobj_f;
        Pobj_d;

        high_quality;
        coord_frame_on;
        ghost_on = 1;

        slider_mode = 0;
        running_flag = 0;

        trj_profile = 3;

        robot_model = 1;
        ee_att = 1;

        kin;
        
        TI_0 = eye(4);

        q_posIni;
        q_posDes;
        
        q_posAct;
        q_velAct;
        q_accAct;

        qEIni;
        qEDes;

        tstp = 0.001; % Time step
        tfin = 0; % Final time
        
    end
    
    methods (Access = public)
        
        function updateRobotModel(app)
            
            app.ModelDropDown.Value = char(app.robot_name(app.robot_model));

            app.kin = kinematicParameters(app.robot_model,app.ee_att);
            
            if app.kin.n == 6
                app.UITable.Data = {app.kin.a_j(1) app.kin.alpha_j(1) app.kin.d_j(1) append(num2str(app.kin.theta_O_j(1)), ' + q1'); ...
                                    app.kin.a_j(2) app.kin.alpha_j(2) app.kin.d_j(2) append(num2str(app.kin.theta_O_j(2)), ' + q2'); ...
                                    app.kin.a_j(3) app.kin.alpha_j(3) app.kin.d_j(3) append(num2str(app.kin.theta_O_j(3)), ' + q3'); ...
                                    app.kin.a_j(4) app.kin.alpha_j(4) app.kin.d_j(4) append(num2str(app.kin.theta_O_j(4)), ' + q4'); ...
                                    app.kin.a_j(5) app.kin.alpha_j(5) app.kin.d_j(5) append(num2str(app.kin.theta_O_j(5)), ' + q5'); ...
                                    app.kin.a_j(6) app.kin.alpha_j(6) app.kin.d_j(6) append(num2str(app.kin.theta_O_j(6)), ' + q6'); ...
                                    app.kin.a_j(7) app.kin.alpha_j(7) app.kin.d_j(7) app.kin.theta_O_j(7)};
            else
                app.UITable.Data = {app.kin.a_j(1) app.kin.alpha_j(1) app.kin.d_j(1) append(num2str(app.kin.theta_O_j(1)), ' + q1'); ...
                                    app.kin.a_j(2) app.kin.alpha_j(2) app.kin.d_j(2) append(num2str(app.kin.theta_O_j(2)), ' + q2'); ...
                                    app.kin.a_j(3) app.kin.alpha_j(3) app.kin.d_j(3) append(num2str(app.kin.theta_O_j(3)), ' + q3'); ...
                                    app.kin.a_j(4) app.kin.alpha_j(4) app.kin.d_j(4) append(num2str(app.kin.theta_O_j(4)), ' + q4'); ...
                                    app.kin.a_j(5) app.kin.alpha_j(5) app.kin.d_j(5) append(num2str(app.kin.theta_O_j(5)), ' + q5'); ...
                                    app.kin.a_j(6) app.kin.alpha_j(6) app.kin.d_j(6) append(num2str(app.kin.theta_O_j(6)), ' + q6'); ...
                                    app.kin.a_j(7) app.kin.alpha_j(7) app.kin.d_j(7) append(num2str(app.kin.theta_O_j(7)), ' + q7'); ...
                                    app.kin.a_j(8) app.kin.alpha_j(8) app.kin.d_j(8) app.kin.theta_O_j(8)};
            end

            addStyle(app.UITable,uistyle('HorizontalAlignment','right'),'table','');
            
            % app.EndEffectorDropDown.ValueChangedFcn = createCallbackFcn(app, @EndEffectorDropDownValueChanged, true);
            % app.EndEffectorDropDown.Position = [93 231 156 22];
        end
        
        function updateEndEffectorModel(app)
            
            app.ee_att

            if app.robot_model == 4 || app.robot_model == 5
                if strcmp(app.EndEffectorDropDown.Value, 'Gripper')
                    app.ee_att = 2;
                end
                app.EndEffectorDropDown.Items = {'None', 'F/T Sensor', 'F/T Sensor + Gripper', 'F/T Sensor + Ball&Spring'};
                if app.ee_att == 2
                    app.EndEffectorDropDown.Value = 'F/T Sensor + Gripper';
                end
            else
                app.EndEffectorDropDown.Items = {'None', 'Gripper'};
                if app.ee_att > 0
                    app.ee_att = 1;
                    app.EndEffectorDropDown.Value = 'Gripper';
                else
                    app.EndEffectorDropDown.Value = 'None';
                end
            end
            
            if app.ee_att == 1
                if app.robot_model == 3
                    app.qEIni = app.kin.qELim(1);
                    app.qEDes = app.kin.qELim(1);
                elseif app.robot_model == 1
                    app.qEIni = app.kin.qELim(2);
                    app.qEDes = app.kin.qELim(2);
                end
                if (app.robot_model ~= 4 && app.robot_model ~= 5)
                    app.GripperSlider.Visible = 'on';
                    app.GripperSliderLabel.Visible = 'on';
                else
                    app.GripperSlider.Visible = 'off';
                    app.GripperSliderLabel.Visible = 'off';
                end
                if (app.robot_model == 1 || app.robot_model == 3)
                    app.GripperSlider.Enable = 'on';
                    app.GripperSliderLabel.Enable = 'on';
                else
                    app.GripperSlider.Enable = 'off';
                    app.GripperSliderLabel.Enable = 'off';
                end
            elseif app.ee_att == 0
                app.GripperSlider.Visible = 'off';
                app.GripperSliderLabel.Visible = 'off';
                app.GripperSlider.Enable = 'off';
                app.GripperSliderLabel.Enable = 'off';
            elseif app.ee_att == 2
                if (app.robot_model ~= 4 && app.robot_model ~= 5)
                    app.GripperSlider.Visible = 'off';
                    app.GripperSliderLabel.Visible = 'off';
                else
                    app.GripperSlider.Visible = 'on';
                    app.GripperSliderLabel.Visible = 'on';
                end
            end

        end

        function updateJointValues(app)

            app.q_posAct = [];
            app.q_posDes = [];
            for j = 1:app.kin.n
                app.q_posAct(j) = (app.kin.q_posLim(j,1)+app.kin.q_posLim(j,2))/2;
                app.q_posDes(j) = (app.kin.q_posLim(j,1)+app.kin.q_posLim(j,2))/2;
            end

        end

        function updateSliderValues(app)
            
            if app.kin.n == 6
                app.Joint7Slider.Visible = 'off';
                app.Joint7SliderLabel.Visible = 'off';
            else
                app.Joint7Slider.Visible = 'on';
                app.Joint7SliderLabel.Visible = 'on';
            end

            app.Joint1Slider.Limits = rad2deg(app.kin.q_posLim(1,:));
            app.Joint2Slider.Limits = rad2deg(app.kin.q_posLim(2,:));
            app.Joint3Slider.Limits = rad2deg(app.kin.q_posLim(3,:));
            app.Joint4Slider.Limits = rad2deg(app.kin.q_posLim(4,:));
            app.Joint5Slider.Limits = rad2deg(app.kin.q_posLim(5,:));
            app.Joint6Slider.Limits = rad2deg(app.kin.q_posLim(6,:));
            if app.kin.n == 7
                app.Joint7Slider.Limits = rad2deg(app.kin.q_posLim(7,:));
            end
            if app.ee_att == 1
                if app.robot_model == 1
                    app.GripperSlider.Limits = app.kin.qELim*1e3;
                elseif app.robot_model == 3
                    app.GripperSlider.Limits = rad2deg(app.kin.qELim);
                end
            end
            if app.slider_mode == 0
                app.Joint1Slider.Value = rad2deg(app.q_posAct(1));
                app.Joint2Slider.Value = rad2deg(app.q_posAct(2));
                app.Joint3Slider.Value = rad2deg(app.q_posAct(3));
                app.Joint4Slider.Value = rad2deg(app.q_posAct(4));
                app.Joint5Slider.Value = rad2deg(app.q_posAct(5));
                app.Joint6Slider.Value = rad2deg(app.q_posAct(6));
                if app.kin.n == 7
                    app.Joint7Slider.Value = rad2deg(app.q_posAct(7));
                end
                if app.ee_att == 1
                    if app.robot_model == 1
                        app.GripperSlider.Value = app.qEIni*1e3;
                    elseif app.robot_model == 3
                        app.GripperSlider.Value = rad2deg(app.kin.qELim(1));
                    end
                end
            else
                app.Joint1Slider.Value = rad2deg(app.q_posDes(1));
                app.Joint2Slider.Value = rad2deg(app.q_posDes(2));
                app.Joint3Slider.Value = rad2deg(app.q_posDes(3));
                app.Joint4Slider.Value = rad2deg(app.q_posDes(4));
                app.Joint5Slider.Value = rad2deg(app.q_posDes(5));
                app.Joint6Slider.Value = rad2deg(app.q_posDes(6));
                if app.kin.n == 7
                    app.Joint7Slider.Value = rad2deg(app.q_posDes(7));
                end
                if app.ee_att == 1
                    if app.robot_model == 1
                        app.GripperSlider.Value = app.qEDes*1e3;
                    elseif app.robot_model == 3
                        app.GripperSlider.Value = rad2deg(app.kin.qELim(1));
                    end
                end
            end
            
        end
        
        function updateSpinnerValues(app)

            if app.kin.n == 6
                app.Axis7SpinnerLabel.Visible = 'off';
                app.Axis7InitialSpinner.Visible = 'off';
                app.Axis7FinalSpinner.Visible = 'off';
            else
                app.Axis7SpinnerLabel.Visible = 'on';
                app.Axis7InitialSpinner.Visible = 'on';
                app.Axis7FinalSpinner.Visible = 'on';
            end

            app.Axis1InitialSpinner.Limits = rad2deg(app.kin.q_posLim(1,:));
            app.Axis2InitialSpinner.Limits = rad2deg(app.kin.q_posLim(2,:));
            app.Axis3InitialSpinner.Limits = rad2deg(app.kin.q_posLim(3,:));
            app.Axis4InitialSpinner.Limits = rad2deg(app.kin.q_posLim(4,:));
            app.Axis5InitialSpinner.Limits = rad2deg(app.kin.q_posLim(5,:));
            app.Axis6InitialSpinner.Limits = rad2deg(app.kin.q_posLim(6,:));
            app.Axis1FinalSpinner.Limits = rad2deg(app.kin.q_posLim(1,:));
            app.Axis2FinalSpinner.Limits = rad2deg(app.kin.q_posLim(2,:));
            app.Axis3FinalSpinner.Limits = rad2deg(app.kin.q_posLim(3,:));
            app.Axis4FinalSpinner.Limits = rad2deg(app.kin.q_posLim(4,:));
            app.Axis5FinalSpinner.Limits = rad2deg(app.kin.q_posLim(5,:));
            app.Axis6FinalSpinner.Limits = rad2deg(app.kin.q_posLim(6,:));

            app.Axis1InitialSpinner.Value = rad2deg(app.q_posAct(1));
            app.Axis2InitialSpinner.Value = rad2deg(app.q_posAct(2));
            app.Axis3InitialSpinner.Value = rad2deg(app.q_posAct(3));
            app.Axis4InitialSpinner.Value = rad2deg(app.q_posAct(4));
            app.Axis5InitialSpinner.Value = rad2deg(app.q_posAct(5));
            app.Axis6InitialSpinner.Value = rad2deg(app.q_posAct(6));
            if app.kin.n == 7
                app.Axis7InitialSpinner.Limits = rad2deg(app.kin.q_posLim(7,:));
                app.Axis7InitialSpinner.Value = rad2deg(app.q_posAct(7));
            end
            
            app.Axis1FinalSpinner.Value = rad2deg(app.q_posDes(1));
            app.Axis2FinalSpinner.Value = rad2deg(app.q_posDes(2));
            app.Axis3FinalSpinner.Value = rad2deg(app.q_posDes(3));
            app.Axis4FinalSpinner.Value = rad2deg(app.q_posDes(4));
            app.Axis5FinalSpinner.Value = rad2deg(app.q_posDes(5));
            app.Axis6FinalSpinner.Value = rad2deg(app.q_posDes(6));
            if app.kin.n == 7
                app.Axis7FinalSpinner.Limits = rad2deg(app.kin.q_posLim(7,:));
                app.Axis7FinalSpinner.Value = rad2deg(app.q_posDes(7));
            end

        end
        
        function updateEnableState(app)
            
            if app.running_flag == 1
                app.RunButton.Enable = 'off';
                app.InterpolationDropDown.Enable = 'off';
                
                app.Axis1InitialSpinner.Enable = 'off';
                app.Axis2InitialSpinner.Enable = 'off';
                app.Axis3InitialSpinner.Enable = 'off';
                app.Axis4InitialSpinner.Enable = 'off';
                app.Axis5InitialSpinner.Enable = 'off';
                app.Axis6InitialSpinner.Enable = 'off';
                app.Axis7InitialSpinner.Enable = 'off';
                    
                app.Axis1FinalSpinner.Enable = 'off';
                app.Axis2FinalSpinner.Enable = 'off';
                app.Axis3FinalSpinner.Enable = 'off';
                app.Axis4FinalSpinner.Enable = 'off';
                app.Axis5FinalSpinner.Enable = 'off';
                app.Axis6FinalSpinner.Enable = 'off';
                app.Axis7FinalSpinner.Enable = 'off';
                
                app.Joint1Slider.Enable = 'off';
                app.Joint2Slider.Enable = 'off';
                app.Joint3Slider.Enable = 'off';
                app.Joint4Slider.Enable = 'off';
                app.Joint5Slider.Enable = 'off';
                app.Joint6Slider.Enable = 'off';
                app.Joint7Slider.Enable = 'off';
                        
                app.VelEditField.Enable = 'off';                
                if app.trj_profile ~= 1
                    app.AccEditField.Enable = 'off';
                end
                
                app.FinalButton.Enable = 'off';
                app.InitialButton.Enable = 'off';
            else
                app.RunButton.Enable = 'on';
                app.InterpolationDropDown.Enable = 'on';
                
                app.Axis1InitialSpinner.Enable = 'on';
                app.Axis2InitialSpinner.Enable = 'on';
                app.Axis3InitialSpinner.Enable = 'on';
                app.Axis4InitialSpinner.Enable = 'on';
                app.Axis5InitialSpinner.Enable = 'on';
                app.Axis6InitialSpinner.Enable = 'on';
                app.Axis7InitialSpinner.Enable = 'on';
                
                app.Axis1FinalSpinner.Enable = 'on';
                app.Axis2FinalSpinner.Enable = 'on';
                app.Axis3FinalSpinner.Enable = 'on';
                app.Axis4FinalSpinner.Enable = 'on';
                app.Axis5FinalSpinner.Enable = 'on';
                app.Axis6FinalSpinner.Enable = 'on';
                app.Axis7FinalSpinner.Enable = 'on';
                
                app.Joint1Slider.Enable = 'on';
                app.Joint2Slider.Enable = 'on';
                app.Joint3Slider.Enable = 'on';
                app.Joint4Slider.Enable = 'on';
                app.Joint5Slider.Enable = 'on';
                app.Joint6Slider.Enable = 'on';
                app.Joint7Slider.Enable = 'on';
                
                app.VelEditField.Enable = 'on';                
                if app.trj_profile ~= 1
                    app.AccEditField.Enable = 'on';
                end
                       
                app.FinalButton.Enable = 'on';
                app.InitialButton.Enable = 'on';
            end
            
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            app.EnablehighqualitymodelsMenu.Checked = 'on';
            app.HighqualitymodelsSwitch.Value = 'On';
            app.high_quality = 1;
            app.EnablecoordinateframeMenu.Checked = 'on';
            app.EnableghostmodelsMenu.Checked = 'on';
            app.coord_frame_on = 1;

            app.InterpolationDropDown.Value = app.trj_profile_text;
            
            app.VelEditField.Value = app.prcnt(1)*100;
            app.AccEditField.Value = app.prcnt(2)*100;
            
            updateRobotModel(app)
            updateEndEffectorModel(app)
            updateJointValues(app)
            updateSliderValues(app)
            updateSpinnerValues(app)
            
            % Plot
            view(app.UIAxes,3)  % Isometric view
            
            xlabel(app.UIAxes,'X')
            ylabel(app.UIAxes,'Y')
            zlabel(app.UIAxes,'Z')
            grid(app.UIAxes,'on')

            set(app.UIAxes,	'Projection','perspective', ...
                            'PlotBoxAspectRatio',[1 1 1], ...
                            'DataAspectRatio',[1 1 1]);
            
            app.UIAxes.Interactions = rotateInteraction;
%             app.UIAxes.Interactions = [zoomInteraction regionZoomInteraction rulerPanInteraction];
            app.UIAxes.Toolbar = [];
%             zlim(app.UIAxes,[-0.6 inf])

            loadModelStruct(app)
            plotAxesOutApp(app)

        end

        % Menu selected function: ExitMenu
        function ExitMenuSelected(app, event)
            delete(app);
        end

        % Menu selected function: AboutMenu
        function AboutMenuSelected(app, event)
            message = sprintf('Robot Simulation Program\nVersion 0.000005');
            uialert(app.UIFigure,message,'About','Icon','info');
        end

        % Value changed function: ModelDropDown
        function ModelDropDownValueChanged(app, event)
            value = app.ModelDropDown.Value;
            
            if (app.robot_model == 4 || app.robot_model == 5) && app.ee_att > 1
                app.ee_att = 1;
                app.EndEffectorDropDown.Value = 'F/T Sensor';
            end

            if (app.robot_model == 1 || app.robot_model == 2 || app.robot_model == 3) && app.ee_att == 1
                app.ee_att = 2;
            end

            for i = size(app.robot_name,1):-1:1
                if strcmp(value, app.robot_name(i))
                    app.robot_model = i;
                end
            end

            updateRobotModel(app)
            updateEndEffectorModel(app)
            updateJointValues(app)
            updateSliderValues(app)
            updateSpinnerValues(app)
            loadModelStruct(app)
            plotAxesOutApp(app)
        end

        % Value changed function: EndEffectorDropDown
        function EndEffectorDropDownValueChanged(app, event)
            value = app.EndEffectorDropDown.Value;
            
            switch value
                case 'Gripper'
                    app.ee_att = 1;
                case 'F/T Sensor'
                    app.ee_att = 1;
                case 'F/T Sensor + Gripper'
                    app.ee_att = 2;
                case 'F/T Sensor + Ball&Spring'
                    app.ee_att = 3;
                otherwise
                    app.ee_att = 0;
            end

            updateRobotModel(app)
            updateEndEffectorModel(app)
            updateSliderValues(app)
            loadModelStruct(app)
            plotAxesOutApp(app)
        end

        % Value changing function: Joint1Slider
        function Joint1SliderValueChanging(app, event)
            if app.slider_mode == 0
                app.q_posAct(1) = deg2rad(event.Value);
                app.Axis1InitialSpinner.Value = event.Value;
            else
                app.q_posDes(1) = deg2rad(event.Value);
                app.Axis1FinalSpinner.Value = event.Value;
            end
            plotAxesOutApp(app)
        end

        % Value changing function: Joint2Slider
        function Joint2SliderValueChanging(app, event)
            if app.slider_mode == 0
                app.q_posAct(2) = deg2rad(event.Value);
                app.Axis2InitialSpinner.Value = event.Value;
            else
                app.q_posDes(2) = deg2rad(event.Value);
                app.Axis2FinalSpinner.Value = event.Value;
            end
            plotAxesOutApp(app)
        end

        % Value changing function: Joint3Slider
        function Joint3SliderValueChanging(app, event)
            if app.slider_mode == 0
                app.q_posAct(3) = deg2rad(event.Value);
                app.Axis3InitialSpinner.Value = event.Value;
            else
                app.q_posDes(3) = deg2rad(event.Value);
                app.Axis3FinalSpinner.Value = event.Value;
            end
            plotAxesOutApp(app)
        end

        % Value changing function: Joint4Slider
        function Joint4SliderValueChanging(app, event)
            if app.slider_mode == 0
                app.q_posAct(4) = deg2rad(event.Value);
                app.Axis4InitialSpinner.Value = event.Value;
            else
                app.q_posDes(4) = deg2rad(event.Value);
                app.Axis4FinalSpinner.Value = event.Value;
            end
            plotAxesOutApp(app)
        end

        % Value changing function: Joint5Slider
        function Joint5SliderValueChanging(app, event)
            if app.slider_mode == 0
                app.q_posAct(5) = deg2rad(event.Value);
                app.Axis5InitialSpinner.Value = event.Value;
            else
                app.q_posDes(5) = deg2rad(event.Value);
                app.Axis5FinalSpinner.Value = event.Value;
            end
            plotAxesOutApp(app)
        end

        % Value changing function: Joint6Slider
        function Joint6SliderValueChanging(app, event)
            if app.slider_mode == 0
                app.q_posAct(6) = deg2rad(event.Value);
                app.Axis6InitialSpinner.Value = event.Value;
            else
                app.q_posDes(6) = deg2rad(event.Value);
                app.Axis6FinalSpinner.Value = event.Value;
            end
            plotAxesOutApp(app)
        end

        % Value changing function: Joint7Slider
        function Joint7SliderValueChanging(app, event)
            if app.slider_mode == 0
                app.q_posAct(7) = deg2rad(event.Value);
                app.Axis7InitialSpinner.Value = event.Value;
            else
                app.q_posDes(7) = deg2rad(event.Value);
                app.Axis7FinalSpinner.Value = event.Value;
            end
            plotAxesOutApp(app)
        end

        % Value changed function: HighqualitymodelsSwitch
        function HighqualitymodelsSwitchValueChanged(app, event)
            value = app.HighqualitymodelsSwitch.Value;

            if strcmpi(value,'On') == 1
                app.high_quality = 1;
                app.EnablehighqualitymodelsMenu.Checked = 'on';
            else
                app.high_quality = 0;
                app.EnablehighqualitymodelsMenu.Checked = 'off';
            end
            
            loadModelStruct(app)
            plotAxesOutApp(app)
        end

        % Menu selected function: EnablehighqualitymodelsMenu
        function EnablehighqualitymodelsMenuSelected(app, event)
            if app.high_quality == 1
                app.EnablehighqualitymodelsMenu.Checked = 'off';
                app.HighqualitymodelsSwitch.Value = 'Off';
                app.high_quality = 0;
            else
                app.EnablehighqualitymodelsMenu.Checked = 'on';
                app.HighqualitymodelsSwitch.Value = 'On';
                app.high_quality = 1;
            end

            loadModelStruct(app)
            plotAxesOutApp(app)
        end

        % Menu selected function: EnablecoordinateframeMenu
        function EnablecoordinateframeMenuSelected(app, event)
            if app.coord_frame_on == 1
                app.EnablecoordinateframeMenu.Checked = 'off';
                app.coord_frame_on = 0;
            else
                app.EnablecoordinateframeMenu.Checked = 'on';
                app.coord_frame_on = 1;
            end

            loadModelStruct(app)
            plotAxesOutApp(app)
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            app.slider_mode = app.FinalButton.Value;
            
            updateSliderValues(app)
        end

        % Value changing function: Axis1InitialSpinner
        function Axis1InitialSpinnerValueChanging(app, event)
            app.q_posAct(1) = deg2rad(event.Value);
            if app.slider_mode == 0
                app.Joint1Slider.Value = event.Value;
            end
            
            plotAxesOutApp(app)
        end

        % Value changing function: Axis2InitialSpinner
        function Axis2InitialSpinnerValueChanging(app, event)
            app.q_posAct(2) = deg2rad(event.Value);
            if app.slider_mode == 0
                app.Joint2Slider.Value = event.Value;
            end
            
            plotAxesOutApp(app)
        end

        % Value changing function: Axis3InitialSpinner
        function Axis3InitialSpinnerValueChanging(app, event)
            app.q_posAct(3) = deg2rad(event.Value);
            if app.slider_mode == 0
                app.Joint3Slider.Value = event.Value;
            end
            
            plotAxesOutApp(app)
        end

        % Value changing function: Axis4InitialSpinner
        function Axis4InitialSpinnerValueChanging(app, event)
            app.q_posAct(4) = deg2rad(event.Value);
            if app.slider_mode == 0
                app.Joint4Slider.Value = event.Value;
            end
            
            plotAxesOutApp(app)
        end

        % Value changing function: Axis5InitialSpinner
        function Axis5InitialSpinnerValueChanging(app, event)
            app.q_posAct(5) = deg2rad(event.Value);
            if app.slider_mode == 0
                app.Joint5Slider.Value = event.Value;
            end
            
            plotAxesOutApp(app)
        end

        % Value changing function: Axis6InitialSpinner
        function Axis6InitialSpinnerValueChanging(app, event)
            app.q_posAct(6) = deg2rad(event.Value);
            if app.slider_mode == 0
                app.Joint6Slider.Value = event.Value;
            end
            
            plotAxesOutApp(app)
        end

        % Value changing function: Axis7InitialSpinner
        function Axis7InitialSpinnerValueChanging(app, event)
            app.q_posAct(7) = deg2rad(event.Value);
            if app.slider_mode == 0
                app.Joint7Slider.Value = event.Value;
            end
            
            plotAxesOutApp(app)
        end

        % Value changing function: Axis1FinalSpinner
        function Axis1FinalSpinnerValueChanging(app, event)
            app.q_posDes(1) = deg2rad(event.Value);
            if app.slider_mode == 1
                app.Joint1Slider.Value = event.Value;
            end
            
            plotAxesOutApp(app)
        end

        % Value changing function: Axis2FinalSpinner
        function Axis2FinalSpinnerValueChanging(app, event)
            app.q_posDes(2) = deg2rad(event.Value);
            if app.slider_mode == 1
                app.Joint2Slider.Value = event.Value;
            end
            
            plotAxesOutApp(app)
        end

        % Value changing function: Axis3FinalSpinner
        function Axis3FinalSpinnerValueChanging(app, event)
            app.q_posDes(3) = deg2rad(event.Value);
            if app.slider_mode == 1
                app.Joint3Slider.Value = event.Value;
            end
            
            plotAxesOutApp(app)
        end

        % Value changing function: Axis4FinalSpinner
        function Axis4FinalSpinnerValueChanging(app, event)
            app.q_posDes(4) = deg2rad(event.Value);
            if app.slider_mode == 1
                app.Joint4Slider.Value = event.Value;
            end
            
            plotAxesOutApp(app)
        end

        % Value changing function: Axis5FinalSpinner
        function Axis5FinalSpinnerValueChanging(app, event)
            app.q_posDes(5) = deg2rad(event.Value);
            if app.slider_mode == 1
                app.Joint5Slider.Value = event.Value;
            end
            
            plotAxesOutApp(app)
        end

        % Value changing function: Axis6FinalSpinner
        function Axis6FinalSpinnerValueChanging(app, event)
            app.q_posDes(6) = deg2rad(event.Value);
            if app.slider_mode == 1
                app.Joint6Slider.Value = event.Value;
            end
            
            plotAxesOutApp(app)
        end

        % Value changing function: Axis7FinalSpinner
        function Axis7FinalSpinnerValueChanging(app, event)
            app.q_posDes(7) = deg2rad(event.Value);
            if app.slider_mode == 1
                app.Joint7Slider.Value = event.Value;
            end
            
            plotAxesOutApp(app)
        end

        % Value changed function: InterpolationDropDown
        function InterpolationDropDownValueChanged(app, event)
            app.trj_profile_text = app.InterpolationDropDown.Value;

            switch app.trj_profile_text
                case 'Linear'
                    app.trj_profile = 1;
                    app.LimitsLabel.Visible = 'on';
                    app.VelEditField.Visible = 'on';
                    app.VelEditFieldLabel.Visible = 'on';
                    app.Label.Visible = 'on';
                    app.AccEditField.Visible = 'off';
                    app.AccEditFieldLabel.Visible = 'off';
                    app.Label_2.Visible = 'off';
                case 'Cubic'
                    app.trj_profile = 2;
                    app.LimitsLabel.Visible = 'on';
                    app.VelEditField.Visible = 'on';
                    app.VelEditFieldLabel.Visible = 'on';
                    app.Label.Visible = 'on';
                    app.AccEditField.Visible = 'on';
                    app.AccEditFieldLabel.Visible = 'on';
                    app.Label_2.Visible = 'on';
                case 'Quintic'
                    app.trj_profile = 3;
                    app.LimitsLabel.Visible = 'on';
                    app.VelEditField.Visible = 'on';
                    app.VelEditFieldLabel.Visible = 'on';
                    app.Label.Visible = 'on';
                    app.AccEditField.Visible = 'on';
                    app.AccEditFieldLabel.Visible = 'on';
                    app.Label_2.Visible = 'on';
                otherwise
                    app.trj_profile = 0;
                    app.LimitsLabel.Visible = 'off';
                    app.VelEditField.Visible = 'off';
                    app.VelEditFieldLabel.Visible = 'off';
                    app.Label.Visible = 'off';
                    app.AccEditField.Visible = 'off';
                    app.AccEditFieldLabel.Visible = 'off';
                    app.Label_2.Visible = 'off';
            end
        end

        % Value changed function: VelEditField
        function VelEditFieldValueChanged(app, event)
            app.prcnt(1) = app.VelEditField.Value/100;
        end

        % Value changed function: AccEditField
        function AccEditFieldValueChanged(app, event)
            app.prcnt(2) = app.AccEditField.Value/100;
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            
            app.running_flag = 1;
            
            updateEnableState(app)
            app.q_posIni = app.q_posAct;

            app.tfin = computeTrjTime(app.q_posIni,app.q_posDes,app.prcnt,app.trj_profile,app.kin.q_velLim,app.kin.q_accLim);
            
            sim_time = 0;
            tic
            for k = 0:app.tfin/app.tstp
                % Trajectory generation
                [app.q_posAct,app.q_velAct,app.q_accAct] = trjGeneration(app,k);
                
                sim_time = sim_time + app.tstp;
                real_time = toc;

                if sim_time > real_time
                    plotAxesOutApp(app)
                    app.secLabel.Text = append(num2str(k*app.tstp,'%.2f'), ' sec');
                end
                % plotAxesOutApp(app)
            end
            
            app.running_flag = 0;

            updateSliderValues(app)
            updateSpinnerValues(app)
            updateEnableState(app)
            plotAxesOutApp(app)
        end

        % Menu selected function: EnableghostmodelsMenu
        function EnableghostmodelsMenuSelected(app, event)
            if app.ghost_on == 1
                app.EnableghostmodelsMenu.Checked = 'off';
                app.ghost_on = 0;
            else
                app.EnableghostmodelsMenu.Checked = 'on';
                app.ghost_on = 1;
            end
            loadModelStruct(app)
            plotAxesOutApp(app)
        end

        % Value changing function: GripperSlider
        function GripperSliderValueChanging(app, event)
            if app.slider_mode == 0
                if app.robot_model == 3
                    app.qEIni = deg2rad(event.Value);
                else
                    app.qEIni = event.Value*1e-3;
                end
            else
                if app.robot_model == 3
                    app.qEDes = deg2rad(event.Value);
                else
                    app.qEDes = event.Value*1e-3;
                end
            end
            plotAxesOutApp(app)
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {480, 480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {266, '1x', 267};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.ExitMenu = uimenu(app.FileMenu);
            app.ExitMenu.MenuSelectedFcn = createCallbackFcn(app, @ExitMenuSelected, true);
            app.ExitMenu.Text = 'Exit';
            app.ViewMenu = uimenu(app.UIFigure);
            app.ViewMenu.Text = 'View';
            app.PlotOptionsMenu = uimenu(app.ViewMenu);
            app.PlotOptionsMenu.Text = 'Plot Options';
            app.EnablehighqualitymodelsMenu = uimenu(app.PlotOptionsMenu);
            app.EnablehighqualitymodelsMenu.MenuSelectedFcn = createCallbackFcn(app, @EnablehighqualitymodelsMenuSelected, true);
            app.EnablehighqualitymodelsMenu.Text = 'Enable high quality models';
            app.EnablecoordinateframeMenu = uimenu(app.PlotOptionsMenu);
            app.EnablecoordinateframeMenu.MenuSelectedFcn = createCallbackFcn(app, @EnablecoordinateframeMenuSelected, true);
            app.EnablecoordinateframeMenu.Text = 'Enable coordinate frame';
            app.EnableghostmodelsMenu = uimenu(app.PlotOptionsMenu);
            app.EnableghostmodelsMenu.MenuSelectedFcn = createCallbackFcn(app, @EnableghostmodelsMenuSelected, true);
            app.EnableghostmodelsMenu.Text = 'Enable ghost models';
            app.ToolsMenu = uimenu(app.UIFigure);
            app.ToolsMenu.Text = 'Tools';
            app.WindowMenu = uimenu(app.UIFigure);
            app.WindowMenu.Text = 'Window';
            app.HelpMenu = uimenu(app.UIFigure);
            app.HelpMenu.Text = 'Help';
            app.AboutMenu = uimenu(app.HelpMenu);
            app.AboutMenu.MenuSelectedFcn = createCallbackFcn(app, @AboutMenuSelected, true);
            app.AboutMenu.Text = 'About';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.LeftPanel);
            app.RobotTab = uitab(app.TabGroup);
            app.RobotTab.Title = 'Robot';
            app.ModelDropDownLabel = uilabel(app.RobotTab);
            app.ModelDropDownLabel.Text = 'Model';
            app.ModelDropDown = uidropdown(app.RobotTab);
            app.ModelDropDown.Items = {'Franka Emika Robot', 'Universal Robots UR3', 'Unitree Z1', 'Stäubli RX160', 'Stäubli RX160L'};
            app.ModelDropDown.ValueChangedFcn = createCallbackFcn(app, @ModelDropDownValueChanged, true);
            app.ModelDropDown.Value = 'Franka Emika Robot';
            app.UITable = uitable(app.RobotTab);
            app.UITable.ColumnName = {'a_j'; 'alpha_j'; 'd_j'; 'theta_j'};
            app.UITable.RowName = {};
            app.EndEffectorDropDownLabel = uilabel(app.RobotTab);
            app.EndEffectorDropDownLabel.Text = 'End-Effector';
            app.EndEffectorDropDown = uidropdown(app.RobotTab);
            app.EndEffectorDropDown.Items = {'None', 'Gripper'};
            app.EndEffectorDropDown.ValueChangedFcn = createCallbackFcn(app, @EndEffectorDropDownValueChanged, true);
            app.EndEffectorDropDown.Value = 'Gripper';
            app.TrajectoryPanel = uipanel(app.LeftPanel);
            app.TrajectoryPanel.Title = 'Trajectory';
            app.LimitsLabel = uilabel(app.TrajectoryPanel);
            app.LimitsLabel.Text = 'Limits:';
            app.Label = uilabel(app.TrajectoryPanel);
            app.Label.Text = '%';
            app.Label_2 = uilabel(app.TrajectoryPanel);
            app.Label_2.Text = '%';
            app.InterpolationLabel = uilabel(app.TrajectoryPanel);
            app.InterpolationLabel.Text = 'Interpolation:';
            app.InterpolationDropDown = uidropdown(app.TrajectoryPanel);
            app.InterpolationDropDown.Items = {'Linear', 'Cubic', 'Quintic'};
            app.InterpolationDropDown.ValueChangedFcn = createCallbackFcn(app, @InterpolationDropDownValueChanged, true);
            app.InterpolationDropDown.Value = 'Quintic';
            app.AccEditFieldLabel = uilabel(app.TrajectoryPanel);
            app.AccEditFieldLabel.Text = 'Acc';
            app.AccEditField = uieditfield(app.TrajectoryPanel, 'numeric');
            app.AccEditField.Limits = [0 100];
            app.AccEditField.ValueChangedFcn = createCallbackFcn(app, @AccEditFieldValueChanged, true);
            app.AccEditField.Value = 10;
            app.VelEditFieldLabel = uilabel(app.TrajectoryPanel);
            app.VelEditFieldLabel.Text = 'Vel';
            app.VelEditField = uieditfield(app.TrajectoryPanel, 'numeric');
            app.VelEditField.Limits = [0 100];
            app.VelEditField.ValueChangedFcn = createCallbackFcn(app, @VelEditFieldValueChanged, true);
            app.VelEditField.Value = 10;
            app.SimulationPanel = uipanel(app.LeftPanel);
            app.SimulationPanel.Title = 'Simulation';
            app.RunButton = uibutton(app.SimulationPanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.DynamicSwitchLabel = uilabel(app.SimulationPanel);
            app.DynamicSwitchLabel.Enable = 'off';
            app.DynamicSwitchLabel.Text = 'Dynamic';
            app.DynamicSwitch = uiswitch(app.SimulationPanel, 'slider');
            app.DynamicSwitch.Enable = 'off';
            app.CenterPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.CenterPanel);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Panel = uipanel(app.CenterPanel);
            app.SlowerLabel = uilabel(app.Panel);
            app.SlowerLabel.Text = '(Slower!)';
            app.HighqualitymodelsSwitchLabel = uilabel(app.Panel);
            app.HighqualitymodelsSwitchLabel.Text = 'High quality models:';
            app.HighqualitymodelsSwitch = uiswitch(app.Panel, 'slider');
            app.HighqualitymodelsSwitch.ValueChangedFcn = createCallbackFcn(app, @HighqualitymodelsSwitchValueChanged, true);
            app.TimeLabel = uilabel(app.Panel);
            app.TimeLabel.Text = 'Time:';
            app.secLabel = uilabel(app.Panel);
            app.secLabel.Text = '0.00 sec';
            app.RightPanel = uipanel(app.GridLayout);
            app.TabGroup_2 = uitabgroup(app.RightPanel);
            app.SliderTab = uitab(app.TabGroup_2);
            app.SliderTab.Title = 'Slider';
            app.Joint1SliderLabel = uilabel(app.SliderTab);
            app.Joint1SliderLabel.Text = 'Joint 1';
            app.Joint1Slider = uislider(app.SliderTab);
            app.Joint1Slider.ValueChangingFcn = createCallbackFcn(app, @Joint1SliderValueChanging, true);
            app.Joint2SliderLabel = uilabel(app.SliderTab);
            app.Joint2SliderLabel.Text = 'Joint 2';
            app.Joint2Slider = uislider(app.SliderTab);
            app.Joint2Slider.ValueChangingFcn = createCallbackFcn(app, @Joint2SliderValueChanging, true);
            app.Joint3SliderLabel = uilabel(app.SliderTab);
            app.Joint3SliderLabel.Text = 'Joint 3';
            app.Joint3Slider = uislider(app.SliderTab);
            app.Joint3Slider.ValueChangingFcn = createCallbackFcn(app, @Joint3SliderValueChanging, true);
            app.Joint4SliderLabel = uilabel(app.SliderTab);
            app.Joint4SliderLabel.Text = 'Joint 4';
            app.Joint4Slider = uislider(app.SliderTab);
            app.Joint4Slider.ValueChangingFcn = createCallbackFcn(app, @Joint4SliderValueChanging, true);
            app.Joint5SliderLabel = uilabel(app.SliderTab);
            app.Joint5SliderLabel.Text = 'Joint 5';
            app.Joint5Slider = uislider(app.SliderTab);
            app.Joint5Slider.ValueChangingFcn = createCallbackFcn(app, @Joint5SliderValueChanging, true);
            app.Joint6SliderLabel = uilabel(app.SliderTab);
            app.Joint6SliderLabel.Text = 'Joint 6';
            app.Joint6Slider = uislider(app.SliderTab);
            app.Joint6Slider.ValueChangingFcn = createCallbackFcn(app, @Joint6SliderValueChanging, true);
            app.Joint7SliderLabel = uilabel(app.SliderTab);
            app.Joint7SliderLabel.Text = 'Joint 7';
            app.Joint7Slider = uislider(app.SliderTab);
            app.Joint7Slider.ValueChangingFcn = createCallbackFcn(app, @Joint7SliderValueChanging, true);
            app.ButtonGroup = uibuttongroup(app.SliderTab);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.InitialButton = uiradiobutton(app.ButtonGroup);
            app.InitialButton.Text = 'Initial';
            app.InitialButton.Value = true;
            app.FinalButton = uiradiobutton(app.ButtonGroup);
            app.FinalButton.Text = 'Final';
            app.MotionPositionLabel = uilabel(app.ButtonGroup);
            app.MotionPositionLabel.Text = 'Motion Position:';
            app.GripperSliderLabel = uilabel(app.SliderTab);
            app.GripperSliderLabel.Text = 'Gripper';
            app.GripperSlider = uislider(app.SliderTab);
            app.GripperSlider.ValueChangingFcn = createCallbackFcn(app, @GripperSliderValueChanging, true);
            app.SpinnerTab = uitab(app.TabGroup_2);
            app.SpinnerTab.Title = 'Spinner';
            app.InitialpointsLabel = uilabel(app.SpinnerTab);
            app.InitialpointsLabel.Text = 'Initial points:';
            app.FinalpointsLabel = uilabel(app.SpinnerTab);
            app.FinalpointsLabel.Text = 'Final points:';
            app.Axis1InitialSpinner = uispinner(app.SpinnerTab);
            app.Axis1InitialSpinner.ValueChangingFcn = createCallbackFcn(app, @Axis1InitialSpinnerValueChanging, true);
            app.Axis1InitialSpinner.Limits = [-160 160];
            app.Axis2InitialSpinner = uispinner(app.SpinnerTab);
            app.Axis2InitialSpinner.ValueChangingFcn = createCallbackFcn(app, @Axis2InitialSpinnerValueChanging, true);
            app.Axis2InitialSpinner.Limits = [-137.5 137.5];
            app.Axis3InitialSpinner = uispinner(app.SpinnerTab);
            app.Axis3InitialSpinner.ValueChangingFcn = createCallbackFcn(app, @Axis3InitialSpinnerValueChanging, true);
            app.Axis3InitialSpinner.Limits = [-150 150];
            app.Axis4InitialSpinner = uispinner(app.SpinnerTab);
            app.Axis4InitialSpinner.ValueChangingFcn = createCallbackFcn(app, @Axis4InitialSpinnerValueChanging, true);
            app.Axis4InitialSpinner.Limits = [-270 270];
            app.Axis5InitialSpinner = uispinner(app.SpinnerTab);
            app.Axis5InitialSpinner.ValueChangingFcn = createCallbackFcn(app, @Axis5InitialSpinnerValueChanging, true);
            app.Axis5InitialSpinner.Limits = [-105 120];
            app.Axis6InitialSpinner = uispinner(app.SpinnerTab);
            app.Axis6InitialSpinner.ValueChangingFcn = createCallbackFcn(app, @Axis6InitialSpinnerValueChanging, true);
            app.Axis6InitialSpinner.Limits = [-270 270];
            app.Axis1SpinnerLabel = uilabel(app.SpinnerTab);
            app.Axis1SpinnerLabel.Text = 'Joint 1';
            app.Axis1FinalSpinner = uispinner(app.SpinnerTab);
            app.Axis1FinalSpinner.ValueChangingFcn = createCallbackFcn(app, @Axis1FinalSpinnerValueChanging, true);
            app.Axis1FinalSpinner.Limits = [-160 160];
            app.Axis2SpinnerLabel = uilabel(app.SpinnerTab);
            app.Axis2SpinnerLabel.Text = 'Joint 2';
            app.Axis2FinalSpinner = uispinner(app.SpinnerTab);
            app.Axis2FinalSpinner.ValueChangingFcn = createCallbackFcn(app, @Axis2FinalSpinnerValueChanging, true);
            app.Axis2FinalSpinner.Limits = [-137.5 137.5];
            app.Axis3SpinnerLabel = uilabel(app.SpinnerTab);
            app.Axis3SpinnerLabel.Text = 'Joint 3';
            app.Axis3FinalSpinner = uispinner(app.SpinnerTab);
            app.Axis3FinalSpinner.ValueChangingFcn = createCallbackFcn(app, @Axis3FinalSpinnerValueChanging, true);
            app.Axis3FinalSpinner.Limits = [-150 150];
            app.Axis4SpinnerLabel = uilabel(app.SpinnerTab);
            app.Axis4SpinnerLabel.Text = 'Joint 4';
            app.Axis4FinalSpinner = uispinner(app.SpinnerTab);
            app.Axis4FinalSpinner.ValueChangingFcn = createCallbackFcn(app, @Axis4FinalSpinnerValueChanging, true);
            app.Axis4FinalSpinner.Limits = [-270 270];
            app.Axis5SpinnerLabel = uilabel(app.SpinnerTab);
            app.Axis5SpinnerLabel.Text = 'Joint 5';
            app.Axis5FinalSpinner = uispinner(app.SpinnerTab);
            app.Axis5FinalSpinner.ValueChangingFcn = createCallbackFcn(app, @Axis5FinalSpinnerValueChanging, true);
            app.Axis5FinalSpinner.Limits = [-105 120];
            app.Axis6SpinnerLabel = uilabel(app.SpinnerTab);
            app.Axis6SpinnerLabel.Text = 'Joint 6';
            app.Axis6FinalSpinner = uispinner(app.SpinnerTab);
            app.Axis6FinalSpinner.ValueChangingFcn = createCallbackFcn(app, @Axis6FinalSpinnerValueChanging, true);
            app.Axis6FinalSpinner.Limits = [-270 270];
            app.Axis7InitialSpinner = uispinner(app.SpinnerTab);
            app.Axis7InitialSpinner.ValueChangingFcn = createCallbackFcn(app, @Axis7InitialSpinnerValueChanging, true);
            app.Axis7InitialSpinner.Limits = [-270 270];
            app.Axis7SpinnerLabel = uilabel(app.SpinnerTab);
            app.Axis7SpinnerLabel.Text = 'Joint 7';
            app.Axis7FinalSpinner = uispinner(app.SpinnerTab);
            app.Axis7FinalSpinner.ValueChangingFcn = createCallbackFcn(app, @Axis7FinalSpinnerValueChanging, true);
            app.Axis7FinalSpinner.Limits = [-270 270];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MainApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end